classdef IVIS_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        IvisExperimentFolderEditFieldLabel  matlab.ui.control.Label
        IvisExperimentFolderEditField  matlab.ui.control.EditField
        BrowseButton                   matlab.ui.control.Button
        PlaceWellsButton               matlab.ui.control.Button
        WellDetectionLabel             matlab.ui.control.Label
        DetectionMethodButtonGroup     matlab.ui.container.ButtonGroup
        ManualButton                   matlab.ui.control.RadioButton
        AutomaticButton                matlab.ui.control.RadioButton
        UITable                        matlab.ui.control.Table
        RightPanel                     matlab.ui.container.Panel
        TabGroup                       matlab.ui.container.TabGroup
        ExperimentImageTab             matlab.ui.container.Tab
        Image                          matlab.ui.control.Image
        WellImageTab                   matlab.ui.container.Tab
        Image2                         matlab.ui.control.Image
        GroupImageTab                  matlab.ui.container.Tab
        Image3                         matlab.ui.control.Image
        TextAreaLabel                  matlab.ui.control.Label
        TextArea                       matlab.ui.control.TextArea
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        experiment_directory    char % Description
        sub_directories         struct
        approximate_well_radii_range = [ 10 20 ] % pixels of well circle radii
        image_scale = 4         % how much to enlarge the image for displaying figure
        sensitivity = 0.86      % for detecting circles, higher is more circles
        well_counts             double % num_well x num_reads, total light in well
        image_handle            matlab.graphics.primitive.Image
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            app.experiment_directory = uigetdir('Select Main Experiment Folder');
            app.IvisExperimentFolderEditField.Value = app.experiment_directory;
            
            empty_names = { 'Group 1'; 'Group 2'; 'Group 3'; 'Group 4'; 'Group 5'; 'Group 6' };
            tdata = table( empty_names, zeros(6, 1 ));
            app.UITable.Data = tdata;
        end

        % Button pushed function: PlaceWellsButton
        function PlaceWellsButtonPushed(app, event)
            
            if app.ManualButton.Value
                app.TextArea.Value = 'Manual';
            elseif app.AutomaticButton.Value
                app.TextArea.Value = 'Automatic';
            end
            %{
            [ app.well_counts, app.image_handle ] = performAnalysis( ...
                app.experiment_directory, app.approximate_well_radii_range, app.image_scale, ...
                app.sensitivity );
            
            saveResults( app.well_counts, app.experiment_directory, app.image_handle );
                        %}
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {598, 598};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {380, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.IvisExperimentFolderEditFieldLabel = uilabel(app.LeftPanel);
            app.IvisExperimentFolderEditFieldLabel.Text = 'Ivis Experiment Folder';
            app.IvisExperimentFolderEditField = uieditfield(app.LeftPanel, 'text');
            app.BrowseButton = uibutton(app.LeftPanel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.PlaceWellsButton = uibutton(app.LeftPanel, 'push');
            app.PlaceWellsButton.ButtonPushedFcn = createCallbackFcn(app, @PlaceWellsButtonPushed, true);
            app.PlaceWellsButton.Text = 'Place Wells';
            app.WellDetectionLabel = uilabel(app.LeftPanel);
            app.WellDetectionLabel.Text = 'Well Detection';
            app.DetectionMethodButtonGroup = uibuttongroup(app.LeftPanel);
            app.DetectionMethodButtonGroup.Title = 'Detection Method';
            app.ManualButton = uiradiobutton(app.DetectionMethodButtonGroup);
            app.ManualButton.Text = 'Manual';
            app.ManualButton.Value = true;
            app.AutomaticButton = uiradiobutton(app.DetectionMethodButtonGroup);
            app.AutomaticButton.Text = 'Automatic';
            app.UITable = uitable(app.LeftPanel);
            app.UITable.ColumnName = {'Group Name'; 'Column 2'};
            app.UITable.RowName = {'''numbered'''};
            app.UITable.ColumnEditable = true;
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.ExperimentImageTab = uitab(app.TabGroup);
            app.ExperimentImageTab.Title = 'Experiment Image';
            app.Image = uiimage(app.ExperimentImageTab);
            app.WellImageTab = uitab(app.TabGroup);
            app.WellImageTab.Title = 'Well Image';
            app.Image2 = uiimage(app.WellImageTab);
            app.GroupImageTab = uitab(app.TabGroup);
            app.GroupImageTab.Title = 'Group Image';
            app.Image3 = uiimage(app.GroupImageTab);
            app.TextAreaLabel = uilabel(app.RightPanel);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.RightPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IVIS_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end